classdef MetodoDeEliminacionDeGauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MtododeEliminacindeGaussLabel  matlab.ui.control.Label
        NmerodevariablesDropDownLabel  matlab.ui.control.Label
        NumeroDeVariablesDropDown      matlab.ui.control.DropDown
        CalcularButton                 matlab.ui.control.Button
        ResultadoLabel                 matlab.ui.control.Label
        TableData                      matlab.ui.control.Table
        TableResultado                 matlab.ui.control.Table
        TableResultadoMatriz           matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumeroDeVariablesDropDown
        function NumeroDeVariablesDropDownValueChanged(app, event)
            value = app.NumeroDeVariablesDropDown.Value;
            set(app.TableData, 'ColumnEditable', true)
            
            switch(value)
                case '2'
                    set(app.TableData, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableData, 'Data', cell(2,3))
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableResultadoMatriz, 'Data', {})
                    set(app.TableResultado, 'Data', {})
                case '3'
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableData, 'Data', cell(3,4))
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableResultadoMatriz, 'Data', {})
                    set(app.TableResultado, 'Data', {})
                case '4'
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableData, 'Data', cell(4,5))
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableResultadoMatriz, 'Data', {})
                    set(app.TableResultado, 'Data', {})
                case '5'
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableData, 'Data', cell(5,6))
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableResultadoMatriz, 'Data', {})
                    set(app.TableResultado, 'Data', {})
                case '6'
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableData, 'Data', cell(6,7))
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableResultadoMatriz, 'Data', {})
                    set(app.TableResultado, 'Data', {})
            end
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            %             uialert('File not found','Invalid File');
            matrizData = get(app.TableData, 'Data');
            matrizData = str2double(matrizData);
            
            m = size(matrizData,1);
            n = size(matrizData,2);
            [m,n]=size(matrizData);
            for j=1:m-1
                for z=2:m
                    if matrizData(j,j)==0
                        t=matrizData(j,:);matrizData(j,:)=matrizData(z,:);
                        matrizData(z,:)=t;
                    end
                end
                for i=j+1:m
                    matrizData(i,:)=matrizData(i,:)-matrizData(j,:)*(matrizData(i,j)/matrizData(j,j));
                end
            end
            x=zeros(1,m);
            for s=m:-1:1
                c=0;
                for k=2:m
                    c=c+matrizData(s,k)*x(k);
                end
                x(s)=(matrizData(s,n)-c)/matrizData(s,s);
            end
            disp('Gauss elimination method:');   
            
            tmnMatrizResult = size(matrizData, 1);
            switch(tmnMatrizResult)
                case 2
                    set(app.TableResultadoMatriz, 'Data', matrizData)
                    set(app.TableResultado, 'RowName', {'X1','X2'}, 'Data', x');
                case 3
                    set(app.TableResultadoMatriz, 'Data', matrizData)
                    set(app.TableResultado, 'RowName', {'X1','X2','X3'}, 'Data', x');
                case 4
                    set(app.TableResultadoMatriz, 'Data', matrizData)
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4'}, 'Data', x');
                case 5
                    set(app.TableResultadoMatriz, 'Data', matrizData)
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5'}, 'Data', x');
                case 6
                    set(app.TableResultadoMatriz, 'Data', matrizData)
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6'}, 'Data', x');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MtododeEliminacindeGaussLabel = uilabel(app.UIFigure);
            app.MtododeEliminacindeGaussLabel.Text = 'Método de Eliminación de Gauss';
            app.NmerodevariablesDropDownLabel = uilabel(app.UIFigure);
            app.NmerodevariablesDropDownLabel.Text = 'Número de variables:';
            app.NumeroDeVariablesDropDown = uidropdown(app.UIFigure);
            app.NumeroDeVariablesDropDown.Items = {'Elija', '2', '3', '4', '5', '6'};
            app.NumeroDeVariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeVariablesDropDownValueChanged, true);
            app.NumeroDeVariablesDropDown.Value = 'Elija';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'CALCULAR ';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado:';
            app.TableData = uitable(app.UIFigure);
            app.TableData.ColumnName = '';
            app.TableData.RowName = {};
            app.TableResultado = uitable(app.UIFigure);
            app.TableResultado.ColumnName = '';
            app.TableResultado.RowName = {};
            app.TableResultadoMatriz = uitable(app.UIFigure);
            app.TableResultadoMatriz.ColumnName = '';
            app.TableResultadoMatriz.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoDeEliminacionDeGauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end